classdef InjectorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        startButton                   matlab.ui.control.Button
        pauseButton                   matlab.ui.control.Button
        DisplayTextArea               matlab.ui.control.TextArea
        setButton                     matlab.ui.control.Button
        setlistDropDownLabel          matlab.ui.control.Label
        setlistDropDown               matlab.ui.control.DropDown
        AuthoritySwitchLabel          matlab.ui.control.Label
        AuthoritySwitch               matlab.ui.control.Switch
        AmountLimitLabel              matlab.ui.control.Label
        AmountinashortperiodLabel     matlab.ui.control.Label
        BaselineLabel                 matlab.ui.control.Label
        BolusLabel                    matlab.ui.control.Label
        TextArea_AmountLimit          matlab.ui.control.TextArea
        TextArea_AmountInShortPeriod  matlab.ui.control.TextArea
        TextArea_Baseline             matlab.ui.control.TextArea
        TextArea_Bolus                matlab.ui.control.TextArea
        inputEditField                matlab.ui.control.TextArea
        inputLabel                    matlab.ui.control.Label
        mlminLabel                    matlab.ui.control.Label
        mlshotLabel                   matlab.ui.control.Label
        mldayLabel                    matlab.ui.control.Label
        mlhrLabel                     matlab.ui.control.Label
        quitButton                    matlab.ui.control.Button
        MonitorLabel                  matlab.ui.control.Label
        TextArea_BaselineforEm        matlab.ui.control.TextArea
        TextArea_BolusforEm           matlab.ui.control.TextArea
        BaselineforEmLabel            matlab.ui.control.Label
        BolusforEmLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        InjectorProcessor
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: AuthoritySwitch
        function AuthoritySwitchValueChanged(app, event)
            value = app.AuthoritySwitch.Value;
            app.InjectorProcessor.updateAuthority(value);            
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            value = app.inputEditField.Value;
            ID = app.setlistDropDown.Value;
            app.InjectorProcessor.updateData(value,ID);
            switch ID
                case 'AmountLimit'
                    app.TextArea_AmountLimit.Value = value;
                case 'AmountInShortPeriod'
                    app.TextArea_AmountInShortPeriod.Value = value;
                case 'Baseline'
                    re = app.InjectorProcessor.checkSpeed(value);
                    if strcmp(re,'t')
                        app.TextArea_Baseline.Value = value;
                        app.DisplayTextArea.Value = 'Baseline set successfully!'; 
                    else
                        app.DisplayTextArea.Value = '0.01 <= Baseline <= 0.1!!!';
                        app.inputEditField.Value = '';
                    end
                case 'BaselineforEm'
                    a = app.InjectorProcessor.getdata('Baseline');
                    b = app.InjectorProcessor.getdata('Bolus');
                    if (strcmp(a,'empty') || strcmp(b,'empty'))
                        app.DisplayTextArea.Value = 'Please set Baseline and Bolus first!'; 
                    else
                        re = app.InjectorProcessor.checkSpeed(value);
                        if strcmp(re,'t')
                            app.TextArea_Baseline.Value = value;
                            app.DisplayTextArea.Value = 'BaselineforEm set successfully!'; 
                        else
                            app.DisplayTextArea.Value = '0.01 <= totalBaseline <= 0.1!!!';
                            app.inputEditField.Value = '';
                        end
                    end
                case 'Bolus'
                    re = app.InjectorProcessor.checkTotalAmount(value);
                    if strcmp(re,'t')
                        app.TextArea_Bolus.Value = value;
                        app.DisplayTextArea.Value = 'Bolus set successfully!';
                    else
                        app.DisplayTextArea.Value = 'Keep 0.2 <= Bolus <= 0.5';
                        app.inputEditField.Value = '';
                    end
                case 'BolusforEm'
                    a = app.InjectorProcessor.getdata('Baseline');
                    b = app.InjectorProcessor.getdata('Bolus');
                    if (strcmp(a,'empty') || strcmp(b,'empty'))
                        app.DisplayTextArea.Value = 'Please set Baseline and Bolus first!'; 
                    else
                        re = app.InjectorProcessor.checkTotalAmount(value);
                        if strcmp(re,'t')
                            app.TextArea_Baseline.Value = value;
                            app.DisplayTextArea.Value = 'BolusforEm set successfully!'; 
                        else
                            app.DisplayTextArea.Value = 'Keep 0.2 <= totalBolus <= 0.5';
                            app.inputEditField.Value = '';
                        end
                    end
                    
            end
        end

        % Value changed function: setlistDropDown
        function setlistDropDownValueChanged(app, event)
            app.DisplayTextArea.Value = '';
            switch app.setlistDropDown.Value
                case 'AmountLimit'
                    if (strcmp(app.TextArea_AmountLimit.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_AmountLimit.Value;
                    else
                        app.inputEditField.Value = '';
                    end
                case 'AmountInShortPeriod'
                    if (strcmp(app.TextArea_AmountInShortPeriod.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_AmountInShortPeriod.Value;
                    else
                        app.inputEditField.Value = '';
                    end
                case 'Baseline'
                    if (strcmp(app.TextArea_Baseline.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_Baseline.Value;
                    else
                        app.inputEditField.Value = '';
                    end
                case 'BaselineforEm'
                    if (strcmp(app.TextArea_Baseline.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_Baseline.Value;
                    else
                        app.inputEditField.Value = '';
                    end
                case 'Bolus'
                    if (strcmp(app.TextArea_Bolus.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_Bolus.Value;
                    else
                        app.inputEditField.Value = '';
                    end
                case 'BolusforEm'
                    if (strcmp(app.TextArea_Baseline.Value,'waiting!') == 0)
                        app.inputEditField.Value = app.TextArea_Baseline.Value;
                    else
                        app.inputEditField.Value = '';
                    end
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            a1 = app.InjectorProcessor.getdata('AmountLimit');
            a2 = app.InjectorProcessor.getdata('AmountInShortPeriod');
            a3 = app.InjectorProcessor.getdata('Baseline');
            a4 = app.InjectorProcessor.getdata('Bolus');
            a5 = app.InjectorProcessor.getdata('BaselineforEm');
            a6 = app.InjectorProcessor.getdata('BolusforEm');
            if (strcmp(a1,'empty') || strcmp(a2,'empty') || strcmp(a3,'empty') || strcmp(a4,'empty')||strcmp(a5,'empty')||strcmp(a6,'empty'))
                app.DisplayTextArea.Value = 'Please enter the necessary parameters！！！';
            else
                app.InjectorProcessor.start();
            end
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            app.InjectorProcessor.stop();
        end

        % Callback function
        function emergencyshotButtonPushed(app, event)
            app.InjectorProcessor.emergencyShot();
        end

        % Button pushed function: quitButton
        function quitButtonPushed(app, event)
            quit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'pause';
            app.DisplayTextArea = uitextarea(app.UIFigure);
            app.setButton = uibutton(app.UIFigure, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.setlistDropDownLabel = uilabel(app.UIFigure);
            app.setlistDropDownLabel.Text = 'set list';
            app.setlistDropDown = uidropdown(app.UIFigure);
            app.setlistDropDown.Items = {'Baseline', 'BaselineforEm', 'Bolus', 'BolusforEm', 'AmountInShortPeriod', 'AmountLimit'};
            app.setlistDropDown.ValueChangedFcn = createCallbackFcn(app, @setlistDropDownValueChanged, true);
            app.setlistDropDown.Value = 'Baseline';
            app.AuthoritySwitchLabel = uilabel(app.UIFigure);
            app.AuthoritySwitchLabel.Text = 'Authority';
            app.AuthoritySwitch = uiswitch(app.UIFigure, 'slider');
            app.AuthoritySwitch.ValueChangedFcn = createCallbackFcn(app, @AuthoritySwitchValueChanged, true);
            app.AmountLimitLabel = uilabel(app.UIFigure);
            app.AmountLimitLabel.Text = {'AmountLimit'; ''};
            app.AmountinashortperiodLabel = uilabel(app.UIFigure);
            app.AmountinashortperiodLabel.Text = 'Amount in a short period';
            app.BaselineLabel = uilabel(app.UIFigure);
            app.BaselineLabel.Text = 'Baseline';
            app.BolusLabel = uilabel(app.UIFigure);
            app.BolusLabel.Text = 'Bolus';
            app.TextArea_AmountLimit = uitextarea(app.UIFigure);
            app.TextArea_AmountLimit.Value = {'3'};
            app.TextArea_AmountInShortPeriod = uitextarea(app.UIFigure);
            app.TextArea_AmountInShortPeriod.Value = {'1'};
            app.TextArea_Baseline = uitextarea(app.UIFigure);
            app.TextArea_Baseline.Value = {'waiting!'};
            app.TextArea_Bolus = uitextarea(app.UIFigure);
            app.TextArea_Bolus.Value = {'waiting!'};
            app.inputEditField = uitextarea(app.UIFigure);
            app.inputLabel = uilabel(app.UIFigure);
            app.inputLabel.Text = 'input';
            app.mlminLabel = uilabel(app.UIFigure);
            app.mlminLabel.Text = 'ml/min';
            app.mlshotLabel = uilabel(app.UIFigure);
            app.mlshotLabel.Text = 'ml/shot';
            app.mldayLabel = uilabel(app.UIFigure);
            app.mldayLabel.Text = 'ml/day';
            app.mlhrLabel = uilabel(app.UIFigure);
            app.mlhrLabel.Text = 'ml/hr';
            app.quitButton = uibutton(app.UIFigure, 'push');
            app.quitButton.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quitButton.Text = 'quit';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.TextArea_BaselineforEm = uitextarea(app.UIFigure);
            app.TextArea_BaselineforEm.Value = {'waiting!'};
            app.TextArea_BolusforEm = uitextarea(app.UIFigure);
            app.TextArea_BolusforEm.Value = {'waiting!'};
            app.BaselineforEmLabel = uilabel(app.UIFigure);
            app.BaselineforEmLabel.Text = 'Baseline';
            app.BolusforEmLabel = uilabel(app.UIFigure);
            app.BolusforEmLabel.Text = 'Bolus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InjectorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end